classdef Visualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisualizationUIFigure      matlab.ui.Figure
        strawberries_percentage    matlab.ui.control.Label
        strawberries               matlab.ui.control.Label
        StrawberriesPickedLabel_2  matlab.ui.control.Label
        SpeedDropDown              matlab.ui.control.DropDown
        SpeedDropDownLabel         matlab.ui.control.Label
        SimulateButton             matlab.ui.control.StateButton
        StrawberriesPickedLabel    matlab.ui.control.Label
        VehiclesDamagesValue       matlab.ui.control.Label
        VehiclesDamagesLabel       matlab.ui.control.Label
        resultsTable               matlab.ui.control.Table
        StepLabel                  matlab.ui.control.Label
        Simaxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        roadTopology % Description
        sim
        comRange
        simPrinter
        step
        callingapp
        logData
        groupsdata
        Dataplotsapp
        numFormat = '%.2f';
        speedofsim
        Totalamount
        Currentamount
    end
    
    methods (Access = private)
        
        function updateStepLabel(app)
            text = 'Step: ';
            text = [text num2str(app.step.current) '/' num2str(app.step.max)];
            app.StepLabel.Text = text;
        end

        function updateSimIndicatorValues(app)
            app.StrawberriesPickedValue.Text = [num2str(app.sim.g(1), app.numFormat)];
            app.VehiclesDamagesValue.Text = [num2str(app.sim.g(3), app.numFormat)];
        end

        function updateResultsTable(app)
            dataResult = app.sim.getResultData();
            app.resultsTable.Data = dataResult;
            app.Currentamount=sum(cell2mat(app.resultsTable.Data(:,6)));
            
        end

        function updateStrawberrylabels(app)
            app.strawberries.Text=[num2str(app.Currentamount), "/", num2str(app.Totalamount)];
            app.strawberries_percentage.Text = [num2str(app.Currentamount*100/app.Totalamount), " %"];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, roadTopology, numOfVehsPerGroups, cycleTime, fileName, algorithm, comRange, logData, groupsdata)
            app.SimulateButton.Value=0;
            app.speedofsim=1/100;
            app.callingapp=mainapp;
            app.Simaxes.Title.Visible="off";
            app.resultsTable.ColumnFormat = {[] [] [] 'numeric' [] [] [] 'numeric' []};
            app.step.current=0;
            app.logData=logData;
            
            app.roadTopology=roadTopology;
            app.comRange=comRange;
            app.groupsdata=groupsdata;
            greenhousecorridors=CorridorStructure(app.roadTopology.nodes, app.roadTopology.adjMatrix);
            greenhousecorridors=greenhousecorridors.init();
            app.sim=Simulator();

            app.sim.initSimulation(greenhousecorridors, numOfVehsPerGroups, app.comRange, fileName, cycleTime, app.logData, algorithm);
            app.simPrinter = SimulationPrinterApp(app.sim, app.Simaxes, app.roadTopology.chambers);
            app.simPrinter.init();
            app.step.max=cycleTime;
            app.updateStepLabel;
            app.Totalamount = app.sim.FruitBushesHandler.totalamount;

            app.Dataplotsapp=dataplots(app, app.groupsdata);
            takesim(app.Dataplotsapp, app.sim);
        end

        % Value changed function: SimulateButton
        function SimulateButtonValueChanged(app, event)

                while ~app.sim.isFinished()
                value = app.SimulateButton.Value;
                app.sim.simulate(1);
                 pause(app.speedofsim);
                app.simPrinter.update();
                app.step.current = app.sim.step;

                if mod(app.step.current, 50)==0
                    update_plot(app.Dataplotsapp);
                    app.sim.updateStats();
                    %app.updateSimIndicatorValues();
                    app.updateResultsTable();
                    app.updateStrawberrylabels;
                end
                app.updateStepLabel();
                if(value == 0)
                    break;
                end
                end
            app.sim.updateStats();
            %app.updateSimIndicatorValues();
            app.updateResultsTable();

        
        end

        % Close request function: VisualizationUIFigure
        function VisualizationUIFigureCloseRequest(app, event)

              selection = uiconfirm(app.VisualizationUIFigure,'Close Simulation?','Confirm Close',...
                        'Icon','warning');
              switch selection
                  case 'OK'
                        
                        if(~isempty(app.Dataplotsapp))
                        delete(app.Dataplotsapp);
                        end

                        reloadinitializebutton(app.callingapp);
                        delete(app.VisualizationUIFigure);

                  case 'Cancel'
                      return
              end

        end

        % Value changed function: SpeedDropDown
        function SpeedDropDownValueChanged(app, event)
            value = app.SpeedDropDown.Value;
        switch value
            case '1x'
                app.speedofsim=(1/100);
            case '2x'
                app.speedofsim=(1/200);
            case '0.5x'
                app.speedofsim=(1/50);
            case '0.25x'
                app.speedofsim=(1/25);
            case '0.1x'
                app.speedofsim=(1/10);
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VisualizationUIFigure = uifigure('Visible', 'off');
            app.VisualizationUIFigure.Name = 'Visualization';
            app.VisualizationUIFigure.CloseRequestFcn = createCallbackFcn(app, @VisualizationUIFigureCloseRequest, true);
            app.Simaxes = uiaxes(app.VisualizationUIFigure);
            xlabel(app.Simaxes, 'X')
            ylabel(app.Simaxes, 'Y')
            zlabel(app.Simaxes, 'Z')
            app.StepLabel = uilabel(app.VisualizationUIFigure);
            app.StepLabel.Text = 'Step: -/-';
            app.resultsTable = uitable(app.VisualizationUIFigure);
            app.resultsTable.ColumnName = {'Gr-Id'; 'Edge-Dist'; 'Velocity'; 'Direction'; 'Battery usage'; 'Num of strawberries'; 'State'; 'Num of repairs'; 'Traveled km'};
            app.resultsTable.RowName = {};
            app.VehiclesDamagesLabel = uilabel(app.VisualizationUIFigure);
            app.VehiclesDamagesLabel.Visible = 'off';
            app.VehiclesDamagesLabel.Text = 'Vehicle damages sum:';
            app.VehiclesDamagesValue = uilabel(app.VisualizationUIFigure);
            app.VehiclesDamagesValue.Visible = 'off';
            app.VehiclesDamagesValue.Text = '0.00';
            app.StrawberriesPickedLabel = uilabel(app.VisualizationUIFigure);
            app.StrawberriesPickedLabel.Text = 'Strawberries amount sum:';
            app.SimulateButton = uibutton(app.VisualizationUIFigure, 'state');
            app.SimulateButton.ValueChangedFcn = createCallbackFcn(app, @SimulateButtonValueChanged, true);
            app.SimulateButton.Text = 'Simulate';
            app.SpeedDropDownLabel = uilabel(app.VisualizationUIFigure);
            app.SpeedDropDownLabel.Text = 'Speed';
            app.SpeedDropDown = uidropdown(app.VisualizationUIFigure);
            app.SpeedDropDown.Items = {'1x', '2x', '0.5x', '0.25x', '0.1x'};
            app.SpeedDropDown.ValueChangedFcn = createCallbackFcn(app, @SpeedDropDownValueChanged, true);
            app.SpeedDropDown.Value = '1x';
            app.StrawberriesPickedLabel_2 = uilabel(app.VisualizationUIFigure);
            app.StrawberriesPickedLabel_2.Text = {'Strawberries amount '; 'percentage:'};
            app.strawberries = uilabel(app.VisualizationUIFigure);
            app.strawberries.Text = '0';
            app.strawberries_percentage = uilabel(app.VisualizationUIFigure);
            app.strawberries_percentage.Text = '0';
            % Show the figure after all components are created
            app.VisualizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Visualization(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VisualizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisualizationUIFigure)
        end
    end
end