classdef anrobsim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnRobSimAppUIFigure             matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        InitializeButton                matlab.ui.control.Button
        DecisionAlgorithmPanel          matlab.ui.container.Panel
        AlgorithmassignmentDropDown     matlab.ui.control.DropDown
        AlgorithmassignmentDropDownLabel  matlab.ui.control.Label
        DecisionAlgorithmDropDown       matlab.ui.control.DropDown
        DecisionAlgorithmDropDownLabel  matlab.ui.control.Label
        BasicPanel                      matlab.ui.container.Panel
        RandomdistributionoffruitsCheckBox  matlab.ui.control.CheckBox
        EnableeditingmapconfigmaybugsimulationCheckBox  matlab.ui.control.CheckBox
        GeneraterandomcfgtofileButton   matlab.ui.control.Button
        LoadcfgfromfileButton           matlab.ui.control.Button
        WidthbetweenpathsEditField      matlab.ui.control.NumericEditField
        WidthbetweenpathsEditFieldLabel  matlab.ui.control.Label
        LengthofsinglepathEditField     matlab.ui.control.NumericEditField
        LengthofsinglepathEditFieldLabel  matlab.ui.control.Label
        NumberofpathsEditField          matlab.ui.control.NumericEditField
        NumberofpathsEditFieldLabel     matlab.ui.control.Label
        LogadditionaldataDropDown       matlab.ui.control.DropDown
        LogadditionaldataDropDownLabel  matlab.ui.control.Label
        CycletimeEditField              matlab.ui.control.NumericEditField
        CycletimesLabel                 matlab.ui.control.Label
        MapConfigurationDropDown        matlab.ui.control.DropDown
        ConfigurationLabel              matlab.ui.control.Label
        GroupsPanel                     matlab.ui.container.Panel
        NumberofvehiclesSpinner         matlab.ui.control.Spinner
        NumberofvehiclesSpinnerLabel    matlab.ui.control.Label
        DeleteselectedgroupButton       matlab.ui.control.Button
        AddnewgroupButton               matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        VehiclesTab                     matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        AdvancedconfigButton            matlab.ui.control.Button
        VehMaxRangeLabel                matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        VehCommunicationMaxRangeSlider  matlab.ui.control.Slider
        RangeLabel_2                    matlab.ui.control.Label
        VehCommunicationRangeDropDown   matlab.ui.control.DropDown
        RangetypeLabel                  matlab.ui.control.Label
        VehiclelengthmEditField         matlab.ui.control.NumericEditField
        VehiclelengthmEditFieldLabel    matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        MaxinitialdistancemEditField    matlab.ui.control.NumericEditField
        MaxinitialdistancemEditFieldLabel  matlab.ui.control.Label
        MininitialdistancemEditField    matlab.ui.control.NumericEditField
        MininitialdistancemEditFieldLabel  matlab.ui.control.Label
        Maxaccelerationms2EditField     matlab.ui.control.NumericEditField
        Maxaccelerationms2Label         matlab.ui.control.Label
        MaxvelocitymsEditField          matlab.ui.control.NumericEditField
        MaxvelocitymsEditFieldLabel     matlab.ui.control.Label
        StatisticalExperimentsTab       matlab.ui.container.Tab
        LoadResultsButton               matlab.ui.control.Button
        comparatorTable                 matlab.ui.control.Table
        EnvironmentTab                  matlab.ui.container.Tab
        RoadtopologyMapsDropDown        matlab.ui.control.DropDown
        RoadtopologyMapsDropDownLabel   matlab.ui.control.Label
        routeTopologyAxes               matlab.ui.control.UIAxes
        SupervisorycontrolTab           matlab.ui.container.Tab
        AnticipatorynetworksTab         matlab.ui.container.Tab
        RobotAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Dataplotsapp;
        Visualizationapp;
        vehicleeditorapp;
        sim % Description
        simPrinter
        roadTopology
        step % Description
        numFormat = '%.2f';
        comRange = 200;
        logData = false; % Description
        resultsFileName = 'results.xml'; % Description
        communicationString % Description
        resultsSaved = false; % Description
        FruitBushesAxesHost;
        FruitBushesFig;
        
    end

    methods (Access = private)
        function numOfVehPerGroup = getNumOfVehsPerGroups(app)
            
         [length, ~]=size(app.UITable.Data);
        numOfVehPerGroup=zeros(length, 1);
         for i = 1:length
             numOfVehPerGroup(i)=app.UITable.Data(i);
         end  

        numOfVehPerGroup=ones(app.NumberofvehiclesSpinner.Value, 1);

        end

        
        function result = getAlgorithm(app)
            switch app.DecisionAlgorithmDropDown.Value
                case 'Base'
                    result = 0;
                case 'Anticipatory'
                    result = 1;
            end
        end
        
        
        function createFruitBushesFig(app)
            app.FruitBushesFig = uifigure('Position', [1000 100 600 600], ...
                'Name', 'FruitBushes Details', ...
                'CloseRequestFcn',@(fig, event) app.closeReq());
            app.FruitBushesAxesHost = app.FruitBushesAxesHost(app.FruitBushesFig);
        end
        
        function closeReq(app)
            selection = questdlg('Do you want to close the simulation?',...
                'Confirmation',...
                'Yes','No','Yes');
            switch selection
                case 'Yes'
                     app.delete;
                    delete(app.visualisationFig);
                    delete(app.FruitBushesFig);
                    app.Dataplotsapp.delete;
                case 'No'
                    return
            end
        end


        function loadConfigList(app)
            cd("configs\");
            filelist=dir("*.xlsx");
            [length,~]=size(filelist);
            for i=1:length
                app.MapConfigurationDropDown.Items=[app.MapConfigurationDropDown.Items filelist(i).name];
            
            end
            filename=app.MapConfigurationDropDown.Value;
            data=readmatrix(filename, "Sheet", "MapConfig", "Range", "B1:B5");
            app.NumberofpathsEditField.Value=data(3,1);
            app.LengthofsinglepathEditField.Value=data(4,1);
            app.WidthbetweenpathsEditField.Value=data(5,1);
            cd('..');

        end
        function resetGui(app)
            app.InitializeButton.Enable = 'on';
            app.CycletimeEditField.Enable = 'on';
            app.MapConfigurationDropDown.Enable = 'on';
            app.VehCommunicationRangeDropDown.Enable = 'on';
            app.VehCommunicationMaxRangeSlider.Enable = 'on';
            app.LogadditionaldataDropDown.Enable = 'on';
            app.DecisionAlgorithmDropDown.Enable = 'on';
            app.step.current = 0;
            app.step.max = 0;
            app.updateStepLabel();
            app.resultsSaved = false;
            cla(app.simAxes);
        end
        
        function updateFruitBushesAxes(app)
            while(~isempty(app.sim.FruitBushesInMitigation))
                app.FruitBushesAxesHost.createFruitBushAxes(app.sim.FruitBushesInMitigation(1));
                app.sim.FruitBushesInMitigation(1) = [];
            end
            app.FruitBushesAxesHost.update();
        end
        
    end
    methods (Access = public)
        
        function reloadinitializebutton(app)
            app.InitializeButton.Enable="on";
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.BasicPanel.Title=pwd;
            app.UITable.RowName='numbered';
            app.UITable.Data=1;
            app.UITable.SelectionType="row";
            app.loadConfigList;
            [app.roadTopology.nodes, app.roadTopology.adjMatrix, app.roadTopology.chambers] = getGreenhouseConfiguration(0, 0, app.NumberofpathsEditField.Value, app.LengthofsinglepathEditField.Value, app.WidthbetweenpathsEditField.Value);
            app.roadTopology.fileName = strcat('configs/',app.MapConfigurationDropDown.Value);
            app.roadTopology.name = 'Simple loop';
            app.communicationString = 'Fixed_200';
            
            app.step.current = 0;
            app.step.max = 0;
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
           
            numOfVehsPerGroups = app.getNumOfVehsPerGroups();
            cycleTime = app.CycletimeEditField.Value;
            [app.roadTopology.nodes, app.roadTopology.adjMatrix, app.roadTopology.chambers] = getGreenhouseConfiguration(0, 0, app.NumberofpathsEditField.Value, app.LengthofsinglepathEditField.Value, app.WidthbetweenpathsEditField.Value);
            fileName = app.roadTopology.fileName;
            algorithm = app.getAlgorithm();


            app.Visualizationapp=Visualization(app, app.roadTopology, numOfVehsPerGroups, cycleTime, fileName, algorithm, app.comRange, app.logData, app.UITable.Data);
            
            app.InitializeButton.Enable="off";
        end

        % Value changed function: LengthofsinglepathEditField, 
        % ...and 2 other components
        function RoadtopologyDropDownValueChanged(app, event)

        end

        % Callback function
        function ResetButtonPushed(app, event)
            app.InitializeButton.Enable = 'on';
            app.NumberEditField.Enable = 'on';
            app.RobotsGR1EditField.Enable = 'on';
            app.RobotsGR2EditField.Enable = 'on';
            app.CycletimeEditField.Enable = 'on';
%             app.RoutetopologyDropDown.Enable = 'on';
            app.MapConfigurationDropDown.Enable = 'on';
            app.VehCommunicationRangeDropDown.Enable = 'on';
            app.VehCommunicationMaxRangeSlider.Enable = 'on';
            app.LogadditionaldataDropDown.Enable = 'on';
            app.DecisionAlgorithmDropDown.Enable = 'on';
            app.step.current = 0;
            app.step.max = 0;
            app.updateStepLabel();
            app.resultsSaved = false;
            cla(app.simAxes);
        end

        % Callback function
        function NumberEditFieldValueChanged(app, event)
            value = app.NumberEditField.Value;
            switch value
                case 1
                    app.RobotsGR2Label.Visible = 'off';
                    app.RobotsGR2EditField.Visible = 'off';
                case 2
                    app.RobotsGR2Label.Visible = 'on';
                    app.RobotsGR2EditField.Visible = 'on';
            end
        end

        % Value changed function: RoadtopologyMapsDropDown
        function RoadtopologyMapsDropDownValueChanged(app, event)
            value = app.RoadtopologyMapsDropDown.Value;
            cla(app.routeTopologyAxes);
            

            if(~isempty(value))
                greenhouse = CorridorStructure(app.roadTopology.nodes, app.roadTopology.adjMatrix);
                greenhouse = greenhouse.init();
                myPrinter = CorridorStructurePrinterApp(greenhouse, app.routeTopologyAxes);
                myPrinter.print();
            end
        end

        % Button pushed function: LoadResultsButton
        function LoadResultsButtonPushed(app, event)
            xml = xml2struct(app.resultsFileName);
            num = length(xml.results.result);
            row = 1;
            data = cell(num, 17);
            for i = 1:num
                cResult = xml.results.result{i};
                data(row, 1) = {cResult.id.Text};
                data(row, 2) = {cResult.settings.routeTopology.Text};
                data(row, 3) = {cResult.settings.configuration.Text};
                data(row, 4) = {cResult.settings.communication.Text};
                data(row, 5) = {cResult.settings.cycleTime.Text};
                data(row, 6) = {cResult.settings.vehicles.Text};
                data(row, 7) = {cResult.settings.algorithm.Text};
                data(row, 8) = {cResult.indicators.G1.Text};
                data(row, 9) = {cResult.indicators.G3.Text};
                data(row, 10) = {cResult.indicators.numOfRepairs.Text};
                data(row, 11) = {cResult.indicators.numOfKm.Text};
                data(row, 12) = {cResult.indicators.meanTimeToDiscoverFruitBush.Text};
                data(row, 13) = {cResult.indicators.sumTimeToDiscoverFruitBush.Text};
                data(row, 14) = {cResult.indicators.mitigationTimePerSeverity.Text};
                data(row, 15) = {cResult.indicators.optContributionInMitFruitBushes.Text};
                data(row, 16) = {cResult.indicators.discoveredFruitBushesPercent.Text};
                data(row, 17) = {cResult.indicators.ReloaddFruitBushesPercent.Text};
                row = row + 1;
            end
            app.comparatorTable.Data = data;
        end

        % Callback function
        function SinglestepButtonPushed(app, event)
            app.sim.simulate(1);
            app.simPrinter.update();
            app.step.current = app.sim.step;
            app.updateStepLabel();
            app.updateGLabels();
            app.updateResultsTable();
        end

        % Callback function
        function CommunicationRangeDropDownValueChanged(app, event)
            %             value = app.CommunicationRangeDropDown.Value;
            %             switch value
            %                 case 'Fixed value'
            %                     app.CommunicationMaxRangeSlider.Enable = 'on';
            %                     app.communicationString = ['Fixed_', int2str(app.comRange)];
            %                 case 'Dynamic'
            %                     app.CommunicationMaxRangeSlider.Enable = 'off';
            %                     app.comRange = -1;
            %                     app.communicationString = 'Dynamic';
            %             end
        end

        % Callback function
        function CommunicationMaxRangeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.MaxRangeLabel.Text = num2str(round(changingValue));
        end

        % Callback function
        function CommunicationMaxRangeSliderValueChanged(app, event)
            value = app.CommunicationMaxRangeSlider.Value;
            app.comRange = round(value);
            app.communicationString = ['Fixed_', int2str(app.comRange)];
        end

        % Value changed function: LogadditionaldataDropDown
        function LogadditionaldataDropDownValueChanged(app, event)
            value = app.LogadditionaldataDropDown.Value;
            switch value
                case 'Disabled'
                    app.logData = false;
                case 'Enabled'
                    app.logData = true;
            end
        end

        % Value changed function: VehCommunicationRangeDropDown
        function VehCommunicationRangeDropDownValueChanged(app, event)
            value = app.VehCommunicationRangeDropDown.Value;
            switch value
                case 'Fixed value'
                    app.VehCommunicationMaxRangeSlider.Enable = 'on';
                    app.communicationString = ['Fixed_', int2str(app.comRange)];
                case 'Dynamic'
                    app.VehCommunicationMaxRangeSlider.Enable = 'off';
                    app.comRange = -1;
                    app.communicationString = 'Dynamic';
            end
        end

        % Value changed function: VehCommunicationMaxRangeSlider
        function VehCommunicationMaxRangeSliderValueChanged(app, event)
            value = app.VehCommunicationMaxRangeSlider.Value;
            app.comRange = round(value);
            app.communicationString = ['Fixed_', int2str(app.comRange)];
        end

        % Value changing function: VehCommunicationMaxRangeSlider
        function VehCommunicationMaxRangeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.VehMaxRangeLabel.Text = num2str(round(changingValue));
            
        end

        % Button pushed function: AddnewgroupButton
        function AddnewgroupButtonPushed(app, event)
          app.UITable.Data=[app.UITable.Data;4];
        end

        % Button pushed function: DeleteselectedgroupButton
        function DeleteselectedgroupButtonPushed(app, event)
            selection=app.UITable.Selection;
            app.UITable.Data(selection, :)=[];
        end

        % Value changed function: 
        % EnableeditingmapconfigmaybugsimulationCheckBox
        function EnableeditingmapconfigmaybugsimulationCheckBoxValueChanged(app, event)
            value = app.EnableeditingmapconfigmaybugsimulationCheckBox.Value;
            if value==1
                app.WidthbetweenpathsEditField.Editable="on";
                app.LengthofsinglepathEditField.Editable="on";
                app.NumberofpathsEditField.Editable="on";
            else
                app.WidthbetweenpathsEditField.Editable="off";
                app.LengthofsinglepathEditField.Editable="off";
                app.NumberofpathsEditField.Editable="off";

            end
        end

        % Close request function: AnRobSimAppUIFigure
        function AnRobSimAppUIFigureCloseRequest(app, event)
              selection = uiconfirm(app.AnRobSimAppUIFigure,'Close Program?','Confirm Close',...
                        'Icon','warning');
              switch selection
                  case 'OK'
                        if(~isempty(app.Dataplotsapp))
                        delete(app.Dataplotsapp);
                        end
                        
                        if(~isempty(app.Visualizationapp))
                        delete(app.Visualizationapp);
                        end
                        delete(app);

                  case 'Cancel'
                      return
              end

        end

        % Button pushed function: AdvancedconfigButton
        function AdvancedconfigButtonPushed(app, event)
            app.vehicleeditorapp=vehicles_editor_strawberries(app);
        end

        % Value changed function: MapConfigurationDropDown
        function MapConfigurationDropDownValueChanged(app, event)
            filename = app.MapConfigurationDropDown.Value;
            cd("configs\");
            data=readmatrix(filename, "Sheet", "MapConfig", "Range", "B1:B5");
            app.NumberofpathsEditField.Value=data(3,1);
            app.LengthofsinglepathEditField.Value=data(4,1);
            app.WidthbetweenpathsEditField.Value=data(5,1);
            cd('..');
        end

        % Value changed function: NumberofvehiclesSpinner
        function NumberofvehiclesSpinnerValueChanged(app, event)
            value = app.NumberofvehiclesSpinner.Value;
            app.UITable.Data=ones(value, 1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AnRobSimAppUIFigure = uifigure('Visible', 'off');
            app.AnRobSimAppUIFigure.Name = 'AnRobSim App';
            app.AnRobSimAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @AnRobSimAppUIFigureCloseRequest, true);
            app.RobotAxes = uiaxes(app.AnRobSimAppUIFigure);
            app.RobotAxes.XTick = [];
            app.RobotAxes.YTick = [];
            app.TabGroup = uitabgroup(app.AnRobSimAppUIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.GroupsPanel = uipanel(app.GeneralTab);
            app.GroupsPanel.Title = 'Groups';
            app.UITable = uitable(app.GroupsPanel);
            app.UITable.ColumnName = {'Number of robots in group'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.Enable = 'off';
            app.UITable.Visible = 'off';
            app.AddnewgroupButton = uibutton(app.GroupsPanel, 'push');
            app.AddnewgroupButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewgroupButtonPushed, true);
            app.AddnewgroupButton.Enable = 'off';
            app.AddnewgroupButton.Visible = 'off';
            app.AddnewgroupButton.Text = 'Add new group';
            app.DeleteselectedgroupButton = uibutton(app.GroupsPanel, 'push');
            app.DeleteselectedgroupButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedgroupButtonPushed, true);
            app.DeleteselectedgroupButton.Enable = 'off';
            app.DeleteselectedgroupButton.Visible = 'off';
            app.DeleteselectedgroupButton.Text = 'Delete selected group';
            app.NumberofvehiclesSpinnerLabel = uilabel(app.GroupsPanel);
            app.NumberofvehiclesSpinnerLabel.Text = 'Number of vehicles';
            app.NumberofvehiclesSpinner = uispinner(app.GroupsPanel);
            app.NumberofvehiclesSpinner.Limits = [1 5];
            app.NumberofvehiclesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofvehiclesSpinnerValueChanged, true);
            app.NumberofvehiclesSpinner.Value = 1;
            app.BasicPanel = uipanel(app.GeneralTab);
            app.BasicPanel.Title = 'Basic';
            app.ConfigurationLabel = uilabel(app.BasicPanel);
            app.ConfigurationLabel.Text = 'Map Configuration:';
            app.MapConfigurationDropDown = uidropdown(app.BasicPanel);
            app.MapConfigurationDropDown.Items = {};
            app.MapConfigurationDropDown.ValueChangedFcn = createCallbackFcn(app, @MapConfigurationDropDownValueChanged, true);
            app.MapConfigurationDropDown.Value = {};
            app.CycletimesLabel = uilabel(app.BasicPanel);
            app.CycletimesLabel.Text = 'Cycle time [s]:';
            app.CycletimeEditField = uieditfield(app.BasicPanel, 'numeric');
            app.CycletimeEditField.Limits = [100 28800];
            app.CycletimeEditField.ValueDisplayFormat = '%.0f';
            app.CycletimeEditField.Value = 3600;
            app.LogadditionaldataDropDownLabel = uilabel(app.BasicPanel);
            app.LogadditionaldataDropDownLabel.Text = 'Log additional data:';
            app.LogadditionaldataDropDown = uidropdown(app.BasicPanel);
            app.LogadditionaldataDropDown.Items = {'Enabled', 'Disabled'};
            app.LogadditionaldataDropDown.ValueChangedFcn = createCallbackFcn(app, @LogadditionaldataDropDownValueChanged, true);
            app.LogadditionaldataDropDown.Value = 'Disabled';
            app.NumberofpathsEditFieldLabel = uilabel(app.BasicPanel);
            app.NumberofpathsEditFieldLabel.Text = 'Number of paths';
            app.NumberofpathsEditField = uieditfield(app.BasicPanel, 'numeric');
            app.NumberofpathsEditField.Limits = [2 Inf];
            app.NumberofpathsEditField.RoundFractionalValues = 'on';
            app.NumberofpathsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofpathsEditField.ValueChangedFcn = createCallbackFcn(app, @RoadtopologyDropDownValueChanged, true);
            app.NumberofpathsEditField.Editable = 'off';
            app.NumberofpathsEditField.Value = 5;
            app.LengthofsinglepathEditFieldLabel = uilabel(app.BasicPanel);
            app.LengthofsinglepathEditFieldLabel.Text = 'Length of single path';
            app.LengthofsinglepathEditField = uieditfield(app.BasicPanel, 'numeric');
            app.LengthofsinglepathEditField.Limits = [200 Inf];
            app.LengthofsinglepathEditField.ValueChangedFcn = createCallbackFcn(app, @RoadtopologyDropDownValueChanged, true);
            app.LengthofsinglepathEditField.Editable = 'off';
            app.LengthofsinglepathEditField.Value = 700;
            app.WidthbetweenpathsEditFieldLabel = uilabel(app.BasicPanel);
            app.WidthbetweenpathsEditFieldLabel.Text = 'Width between paths';
            app.WidthbetweenpathsEditField = uieditfield(app.BasicPanel, 'numeric');
            app.WidthbetweenpathsEditField.Limits = [0 Inf];
            app.WidthbetweenpathsEditField.ValueChangedFcn = createCallbackFcn(app, @RoadtopologyDropDownValueChanged, true);
            app.WidthbetweenpathsEditField.Editable = 'off';
            app.WidthbetweenpathsEditField.Value = 50;
            app.LoadcfgfromfileButton = uibutton(app.BasicPanel, 'push');
            app.LoadcfgfromfileButton.Text = 'Load cfg from file';
            app.GeneraterandomcfgtofileButton = uibutton(app.BasicPanel, 'push');
            app.GeneraterandomcfgtofileButton.Enable = 'off';
            app.GeneraterandomcfgtofileButton.Text = 'Generate random cfg to file';
            app.EnableeditingmapconfigmaybugsimulationCheckBox = uicheckbox(app.BasicPanel);
            app.EnableeditingmapconfigmaybugsimulationCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableeditingmapconfigmaybugsimulationCheckBoxValueChanged, true);
            app.EnableeditingmapconfigmaybugsimulationCheckBox.Text = 'Enable editing map config(may bug simulation!)';
            app.RandomdistributionoffruitsCheckBox = uicheckbox(app.BasicPanel);
            app.RandomdistributionoffruitsCheckBox.Text = 'Random distribution of fruits';
            app.DecisionAlgorithmPanel = uipanel(app.GeneralTab);
            app.DecisionAlgorithmPanel.Title = 'Decision Algorithm';
            app.DecisionAlgorithmDropDownLabel = uilabel(app.DecisionAlgorithmPanel);
            app.DecisionAlgorithmDropDownLabel.Text = 'Decision Algorithm:';
            app.DecisionAlgorithmDropDown = uidropdown(app.DecisionAlgorithmPanel);
            app.DecisionAlgorithmDropDown.Items = {'Base', 'Anticipatory'};
            app.DecisionAlgorithmDropDown.Value = 'Anticipatory';
            app.AlgorithmassignmentDropDownLabel = uilabel(app.DecisionAlgorithmPanel);
            app.AlgorithmassignmentDropDownLabel.Text = 'Algorithm assignment:';
            app.AlgorithmassignmentDropDown = uidropdown(app.DecisionAlgorithmPanel);
            app.AlgorithmassignmentDropDown.Items = {'Homogeneus algorithm', 'Homogeneus groups', 'Indyvidual algorithm assignment'};
            app.AlgorithmassignmentDropDown.Enable = 'off';
            app.AlgorithmassignmentDropDown.Value = 'Homogeneus algorithm';
            app.InitializeButton = uibutton(app.GeneralTab, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.VehiclesTab = uitab(app.TabGroup);
            app.VehiclesTab.Title = 'Vehicles';
            app.MaxvelocitymsEditFieldLabel = uilabel(app.VehiclesTab);
            app.MaxvelocitymsEditFieldLabel.Text = 'Max velocity [m/s]:';
            app.MaxvelocitymsEditField = uieditfield(app.VehiclesTab, 'numeric');
            app.MaxvelocitymsEditField.Limits = [0 2];
            app.MaxvelocitymsEditField.ValueDisplayFormat = '%.2f';
            app.MaxvelocitymsEditField.Value = 1;
            app.Maxaccelerationms2Label = uilabel(app.VehiclesTab);
            app.Maxaccelerationms2Label.Text = 'Max acceleration [m/s^2]:';
            app.Maxaccelerationms2EditField = uieditfield(app.VehiclesTab, 'numeric');
            app.Maxaccelerationms2EditField.Limits = [0 2];
            app.Maxaccelerationms2EditField.ValueDisplayFormat = '%.2f';
            app.Maxaccelerationms2EditField.Editable = 'off';
            app.Maxaccelerationms2EditField.Value = 1;
            app.MininitialdistancemEditFieldLabel = uilabel(app.VehiclesTab);
            app.MininitialdistancemEditFieldLabel.Text = 'Min initial distance [m]:';
            app.MininitialdistancemEditField = uieditfield(app.VehiclesTab, 'numeric');
            app.MininitialdistancemEditField.Limits = [2 15];
            app.MininitialdistancemEditField.ValueDisplayFormat = '%.0f';
            app.MininitialdistancemEditField.Editable = 'off';
            app.MininitialdistancemEditField.Value = 10;
            app.MaxinitialdistancemEditFieldLabel = uilabel(app.VehiclesTab);
            app.MaxinitialdistancemEditFieldLabel.Text = 'Max initial distance [m]:';
            app.MaxinitialdistancemEditField = uieditfield(app.VehiclesTab, 'numeric');
            app.MaxinitialdistancemEditField.Limits = [15 30];
            app.MaxinitialdistancemEditField.ValueDisplayFormat = '%.0f';
            app.MaxinitialdistancemEditField.Editable = 'off';
            app.MaxinitialdistancemEditField.Value = 20;
            app.Label = uilabel(app.VehiclesTab);
            app.Label.Text = '_ _ _ _ _ _ _ _ _ _ _ ';
            app.Label_2 = uilabel(app.VehiclesTab);
            app.Label_2.Text = '_ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ ';
            app.VehiclelengthmEditFieldLabel = uilabel(app.VehiclesTab);
            app.VehiclelengthmEditFieldLabel.Text = 'Vehicle length [m]:';
            app.VehiclelengthmEditField = uieditfield(app.VehiclesTab, 'numeric');
            app.VehiclelengthmEditField.Limits = [0.5 3];
            app.VehiclelengthmEditField.ValueDisplayFormat = '%.2f';
            app.VehiclelengthmEditField.Editable = 'off';
            app.VehiclelengthmEditField.Value = 1.3;
            app.RangetypeLabel = uilabel(app.VehiclesTab);
            app.RangetypeLabel.Text = 'Communication range type:';
            app.VehCommunicationRangeDropDown = uidropdown(app.VehiclesTab);
            app.VehCommunicationRangeDropDown.Items = {'Fixed value', 'Dynamic'};
            app.VehCommunicationRangeDropDown.ValueChangedFcn = createCallbackFcn(app, @VehCommunicationRangeDropDownValueChanged, true);
            app.VehCommunicationRangeDropDown.Value = 'Fixed value';
            app.RangeLabel_2 = uilabel(app.VehiclesTab);
            app.RangeLabel_2.Text = 'Range:';
            app.VehCommunicationMaxRangeSlider = uislider(app.VehiclesTab);
            app.VehCommunicationMaxRangeSlider.Limits = [10 900];
            app.VehCommunicationMaxRangeSlider.MajorTicks = [10 120 230 340 450 560 670 780 890 1000];
            app.VehCommunicationMaxRangeSlider.ValueChangedFcn = createCallbackFcn(app, @VehCommunicationMaxRangeSliderValueChanged, true);
            app.VehCommunicationMaxRangeSlider.ValueChangingFcn = createCallbackFcn(app, @VehCommunicationMaxRangeSliderValueChanging, true);
            app.VehCommunicationMaxRangeSlider.Value = 300;
            app.Label_3 = uilabel(app.VehiclesTab);
            app.Label_3.Text = '_ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ ';
            app.VehMaxRangeLabel = uilabel(app.VehiclesTab);
            app.VehMaxRangeLabel.Text = '300';
            app.AdvancedconfigButton = uibutton(app.VehiclesTab, 'push');
            app.AdvancedconfigButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedconfigButtonPushed, true);
            app.AdvancedconfigButton.Text = 'Advanced config';
            app.Image = uiimage(app.VehiclesTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Robot-rolniczy-lewy.png');
            app.StatisticalExperimentsTab = uitab(app.TabGroup);
            app.StatisticalExperimentsTab.Title = 'Statistical Experiments';
            app.comparatorTable = uitable(app.StatisticalExperimentsTab);
            app.comparatorTable.ColumnName = {'Id'; 'Topology'; 'Configuration'; 'Communication'; 'Cycle time'; 'Groups'; 'Algorithm'; 'G1'; 'G3'; 'Sum of repairs'; 'Sum of km'; 'Mitigation t. per severity'; 'Opt. team contribution'};
            app.comparatorTable.RowName = {};
            app.LoadResultsButton = uibutton(app.StatisticalExperimentsTab, 'push');
            app.LoadResultsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadResultsButtonPushed, true);
            app.LoadResultsButton.Text = 'Load Results';
            app.EnvironmentTab = uitab(app.TabGroup);
            app.EnvironmentTab.Title = 'Environment';
            app.routeTopologyAxes = uiaxes(app.EnvironmentTab);
            title(app.routeTopologyAxes, 'Route topology')
            xlabel(app.routeTopologyAxes, 'x [m]')
            ylabel(app.routeTopologyAxes, 'y [m]')
            app.RoadtopologyMapsDropDownLabel = uilabel(app.EnvironmentTab);
            app.RoadtopologyMapsDropDownLabel.Text = 'Route topology:';
            app.RoadtopologyMapsDropDown = uidropdown(app.EnvironmentTab);
            app.RoadtopologyMapsDropDown.Items = {'', 'Simple loop', 'Two loops', 'Greenhouse example', 'Test'};
            app.RoadtopologyMapsDropDown.ValueChangedFcn = createCallbackFcn(app, @RoadtopologyMapsDropDownValueChanged, true);
            app.RoadtopologyMapsDropDown.Value = '';
            app.SupervisorycontrolTab = uitab(app.TabGroup);
            app.SupervisorycontrolTab.Title = 'Supervisory control';
            app.AnticipatorynetworksTab = uitab(app.TabGroup);
            app.AnticipatorynetworksTab.Title = 'Anticipatory networks';
            % Show the figure after all components are created
            app.AnRobSimAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = anrobsim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnRobSimAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnRobSimAppUIFigure)
        end
    end
end