classdef Visualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisualizationUIFigure  matlab.ui.Figure
        ControlPanel           matlab.ui.container.Panel
        StepLabel              matlab.ui.control.Label
        CurrentstatsLabel      matlab.ui.control.Label
        SimulateButton         matlab.ui.control.StateButton
        SpeedDropDown          matlab.ui.control.DropDown
        SpeedDropDownLabel     matlab.ui.control.Label
        SpeedSlider            matlab.ui.control.Slider
        SpeedSliderLabel       matlab.ui.control.Label
        resultsTable           matlab.ui.control.Table
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        simulation
        step
        callingapp
        logData
        groupsdata
        Dataplotsapp
        numFormat = '%.2f';
        speedofsim
    end
    
    methods (Access = private)
        
        function updateStepLabel(app)
            text = 'Step: ';
            text = [text num2str(app.step.current) '/' num2str(app.step.max)];
            app.StepLabel.Text = text;
        end

        function updateSimIndicatorValues(app)
            app.StrawberriesPickedValue.Text = [num2str(app.sim.g(1), app.numFormat)];
            app.VehiclesDamagesValue.Text = [num2str(app.sim.g(3), app.numFormat)];
        end

        function updateResultsTable(app)
            dataResult = app.sim.getResultData();
            app.resultsTable.Data = dataResult;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %mainapp, roadTopology, numOfVehsPerGroups, cycleTime, fileName, algorithm, comRange, logData, groupsdata
            
            app.UIAxes.XTick=0:25:900;
            app.UIAxes.YTick=0:5:96;
            %cd Controllers\;
    hold(app.UIAxes, "on");
    GH = Greenhouse(96, 900);

    plot(app.UIAxes, GH.Graphics);
    v1 = Vehicle(5, 5, GH);
        v1.SetSpeed(1);
        
        v1.SetTurn(45);

     for i=1:600
         v1.step();
         g=patch(app.UIAxes, v1.graphics_xcoords+i*1, v1.graphics_ycoords+i*1, 'r');
         drawnow;
        
         pause(1/60);
         delete(g);
     end
    %main loop
           
        end

        % Value changed function: SimulateButton
        function SimulateButtonValueChanged(app, event)

                while ~app.sim.isFinished()
                value = app.SimulateButton.Value;
                app.sim.simulate(1);
                 pause(app.speedofsim);
                app.simPrinter.update();
                app.step.current = app.sim.step;

                if mod(app.step.current, 50)==0
                    update_plot(app.Dataplotsapp);
                    app.sim.updateStats();
                    app.updateSimIndicatorValues();
                    app.updateResultsTable();
                end
                app.updateStepLabel();
                if(value == 0)
                    break;
                end
                end
            app.sim.updateStats();
            app.updateSimIndicatorValues();
            app.updateResultsTable();

        
        end

        % Close request function: VisualizationUIFigure
        function VisualizationUIFigureCloseRequest(app, event)

              %selection = uiconfirm(app.VisualizationUIFigure,'Close Simulation?','Confirm Close',...
                %        'Icon','warning');
              %switch selection
                 % case 'OK'
                        
                  %      if(~isempty(app.Dataplotsapp))
                   %     delete(app.Dataplotsapp);
                    %    end

                     %   reloadinitializebutton(app.callingapp);
                        delete(app.VisualizationUIFigure);

                  %case 'Cancel'
                   %   return
              %end

        end

        % Value changed function: SpeedDropDown
        function SpeedDropDownValueChanged(app, event)
            value = app.SpeedDropDown.Value;
        switch value
            case '1x'
                app.speedofsim=(1/100);
            case '2x'
                app.speedofsim=(1/200);
            case '0.5x'
                app.speedofsim=(1/50);
            case '0.25x'
                app.speedofsim=(1/25);
            case '0.1x'
                app.speedofsim=(1/10);
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VisualizationUIFigure = uifigure('Visible', 'off');
            app.VisualizationUIFigure.Name = 'Visualization';
            app.VisualizationUIFigure.CloseRequestFcn = createCallbackFcn(app, @VisualizationUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.VisualizationUIFigure);
            title(app.UIAxes, 'Greenhouse view')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 900];
            app.UIAxes.YLim = [0 96];
            app.resultsTable = uitable(app.VisualizationUIFigure);
            app.resultsTable.ColumnName = {'Gr-Id'; 'Edge-Dist'; 'Velocity'; 'Direction'; 'Battery usage'; 'Damages'; 'State'; 'Num of repairs'; 'Traveled km'};
            app.resultsTable.RowName = {};
            app.ControlPanel = uipanel(app.VisualizationUIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.SpeedSliderLabel = uilabel(app.ControlPanel);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.ControlPanel);
            app.SpeedSlider.Limits = [1 7];
            app.SpeedSlider.MajorTicks = [1 2 3 4 5 6 7];
            app.SpeedSlider.MajorTickLabels = {'0.1', '0.25', '0.5', '1', '1.5', '2', '4'};
            app.SpeedSlider.MinorTicks = [];
            app.SpeedSlider.Value = 4;
            app.SpeedDropDownLabel = uilabel(app.ControlPanel);
            app.SpeedDropDownLabel.Text = 'Speed';
            app.SpeedDropDown = uidropdown(app.ControlPanel);
            app.SpeedDropDown.Items = {'1x', '2x', '0.5x', '0.25x', '0.1x'};
            app.SpeedDropDown.ValueChangedFcn = createCallbackFcn(app, @SpeedDropDownValueChanged, true);
            app.SpeedDropDown.Value = '1x';
            app.SimulateButton = uibutton(app.ControlPanel, 'state');
            app.SimulateButton.ValueChangedFcn = createCallbackFcn(app, @SimulateButtonValueChanged, true);
            app.SimulateButton.Text = 'Simulate';
            app.CurrentstatsLabel = uilabel(app.ControlPanel);
            app.CurrentstatsLabel.Text = 'Current stats';
            app.StepLabel = uilabel(app.ControlPanel);
            app.StepLabel.Text = 'Step: -/-';
            % Show the figure after all components are created
            app.VisualizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Visualization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VisualizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisualizationUIFigure)
        end
    end
end