classdef dataplots < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataPlotsUIFigure          matlab.ui.Figure
        DisplayoptionsMenu         matlab.ui.container.Menu
        ShowvehiclepanelMenu       matlab.ui.container.Menu
        TraveleddistanceunitsMenu  matlab.ui.container.Menu
        kmMenu                     matlab.ui.container.Menu
        mMenu                      matlab.ui.container.Menu
        ImportmodeMenu             matlab.ui.container.Menu
        ExportdataMenu             matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        VisiblevehiclesPanel       matlab.ui.container.Panel
        Tree_2                     matlab.ui.container.CheckBoxTree
        UIAxesBatteryUsage         matlab.ui.control.UIAxes
        UIAxesDamage               matlab.ui.control.UIAxes
        UIAxesSpeed                matlab.ui.control.UIAxes
        UIAxesTraveled             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        callingapp;
        Batteryusagedata=0;
        Traveledkmdata;
        x_axis=0;
        y_damage=0;
        y_batteryusage=0;
        y_speed=0;
        y_traveled=0;
        y_damageavg=0;
        y_batteryusageavg=0;
        y_traveledavg=0;
        y_speedavg=0;
        Dmgdata;
        Speeddata;
        steps;
        simobject;
        grouptable;
        damageplot
        s;
        selectedvehicles;
        multiplier;
    end
    

    methods (Access= public)

        
        function update_plot(app)
             n=app.simobject.step;
                    
                    
                    app.x_axis=[app.x_axis n];
                    xlim(app.UIAxesDamage, [0 n]);
                    xlim(app.UIAxesBatteryUsage, [0 n]);
                    xlim(app.UIAxesSpeed, [0 n]);
                    xlim(app.UIAxesTraveled, [0 n]);

                    app.y_damage=[app.y_damage zeros(sum(app.grouptable), 1)];
                    app.y_batteryusage=[app.y_batteryusage zeros(sum(app.grouptable), 1)];
                    app.y_speed=[app.y_speed zeros(sum(app.grouptable), 1)];
                    app.y_traveled=[app.y_traveled zeros(sum(app.grouptable), 1)];
                    
                    app.y_damageavg=[app.y_damageavg zeros(size(app.grouptable, 1), 1)];
                    app.y_batteryusageavg=[app.y_batteryusageavg zeros(size(app.grouptable, 1), 1)];
                    app.y_traveledavg=[app.y_traveledavg zeros(size(app.grouptable, 1), 1)];
                    app.y_speedavg=[app.y_speedavg zeros(size(app.grouptable, 1), 1)];


                    for i=1:size(app.grouptable, 1)
                            current_avgdamage=0;
                            current_avgbatteryusage=0;
                            current_avgtraveled=0;
                            current_avgspeed=0;
                        for j=1:app.grouptable(i)
                            
           
                            current_damage = app.simobject.vehicleGroups(i).vehicles(j).damage;
                            current_avgdamage=current_avgdamage+current_damage;


                            current_batteryusage = app.simobject.vehicleGroups(i).vehicles(j).energyConsumption;
                            current_avgbatteryusage = current_avgbatteryusage + current_batteryusage;


                            current_speed = app.simobject.vehicleGroups(i).vehicles(j).vCurrent;
                            current_avgspeed=current_avgspeed+current_speed;


                            current_distance = app.simobject.vehicleGroups(i).vehicles(j).statNumOfKm;
                            current_avgtraveled=current_avgtraveled+current_distance;

                            y_index=0;
                            if i>1
                            for k=1:(i-1)
                                y_index=y_index+app.grouptable(k);
                            end
                            y_index=y_index+j;
                            else
                                y_index=j;
                            end
                            app.y_damage(y_index, size(app.y_damage, 2))=current_damage;
                            app.y_batteryusage(y_index, size(app.y_damage, 2))=current_batteryusage;
                            app.y_speed(y_index, size(app.y_damage, 2))=current_speed;
                            app.y_traveled(y_index, size(app.y_damage, 2))=current_distance;
                            
                            
                            if j==app.grouptable(i)
                                current_avgdamage=current_avgdamage/j;
                                current_avgspeed=current_avgspeed/j;
                                current_avgtraveled=current_avgtraveled/j;
                                current_avgbatteryusage=current_avgbatteryusage/j;

                                app.y_damageavg(i, size(app.y_damageavg, 2))=current_avgdamage;
                                app.y_batteryusageavg(i, size(app.y_damageavg, 2))=current_avgbatteryusage;
                                app.y_speedavg(i, size(app.y_speedavg, 2))=current_avgspeed;
                                app.y_traveledavg(i, size(app.y_traveledavg, 2))=current_avgtraveled;

                                for l=1:size(app.selectedvehicles, 1)
                                    test=app.selectedvehicles(l, :);
                                    if isequal(test, [i 0])
                                        
                                       plot(app.UIAxesDamage, app.x_axis, 10*app.y_damageavg(i, :), "LineWidth", 2.5, "LineStyle", "-");
                                        hold(app.UIAxesDamage, 'on');
        
                                        plot(app.UIAxesSpeed, app.x_axis, app.y_speedavg(i, :), "LineWidth", 2.5, "LineStyle", "-");
                                        hold(app.UIAxesSpeed, 'on');
        
                                        plot(app.UIAxesTraveled, app.x_axis, app.multiplier*app.y_traveledavg(i, :), "LineWidth", 2.5, "LineStyle", "-");
                                        hold(app.UIAxesTraveled, 'on');
        
                                        plot(app.UIAxesBatteryUsage, app.x_axis, app.y_batteryusageavg(i, :), "LineWidth", 2.5, "LineStyle", "-");
                                        hold(app.UIAxesBatteryUsage, 'on');


                                    end
                                end


                            end
                            if size(app.selectedvehicles,1)>0
                            for l=1:size(app.selectedvehicles,1)
                                test=app.selectedvehicles(l,:);
                                if isequal(test, [i j+1])
                                plot(app.UIAxesDamage, app.x_axis, 10*app.y_damage(y_index, :), "LineWidth", 3);
                                hold(app.UIAxesDamage, 'on');

                                plot(app.UIAxesSpeed, app.x_axis, app.y_speed(y_index, :), "LineWidth", 3);
                                hold(app.UIAxesSpeed, 'on');

                                plot(app.UIAxesTraveled, app.x_axis, app.multiplier*app.y_traveled(y_index, :), "LineWidth", 3);
                                hold(app.UIAxesTraveled, 'on');

                                plot(app.UIAxesBatteryUsage, app.x_axis, app.y_batteryusage(y_index, :), "LineWidth", 3);
                                hold(app.UIAxesBatteryUsage, 'on');
                                
                                break;
                                end
                                    
                            end
                            end
                        end


                    end
                    hold (app.UIAxesDamage, 'off');
                    hold(app.UIAxesSpeed, 'off');
                    hold(app.UIAxesTraveled, 'off');
                    hold(app.UIAxesBatteryUsage, 'off');
                   
                    end


            function [returntable, averages] = generatenamestab(app)
                returntable=[];
                averages=[];
            for i=1:size(app.grouptable, 1)
                for j = 1:app.grouptable(i)
                    text = strcat(num2str(i), ' - ', num2str(j));
                    returntable=[returntable; text];
                end
            end

             for i=1:size(app.grouptable, 1)
                text=strcat('Group ', num2str(i), ' avg.');
                averages=[averages; text];
             end
        end
    

        function generatetree(app)
            

            [numofgroups, ~]=size(app.grouptable);
            
            for i=1:numofgroups
                text = strcat("Group ", num2str(i));
                parent = uitreenode(app.Tree_2, "Text", text, "NodeData", [i 1]);
                for j=1:app.grouptable(i)
                      text = strcat("Veh ", num2str(i), "-", num2str(j));
                      uitreenode(parent, "Text", text, "NodeData", [i j+1]);
                      if j==app.grouptable(i)
                         text = strcat("Avg gr. ", num2str(i));
                        avgnode = uitreenode(parent, "Text", text, "NodeData", [i 0]);
                        app.Tree_2.CheckedNodes=[app.Tree_2.CheckedNodes; avgnode];
                      end
                end

            end
            expand(app.Tree_2, 'all');
        end
    
    end
    methods (Access = public)
        function takesim(app, sim)
        app.simobject=sim;
        end
        function startmainloop(app)
             app.update_plot();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, grouptable)
          
            app.callingapp=mainapp;
            app.grouptable=grouptable;
            app.y_damage=zeros(sum(grouptable), 1);
            app.y_batteryusage=zeros(sum(grouptable), 1);
            app.y_speed=zeros(sum(grouptable), 1);
            app.y_traveled=zeros(sum(grouptable), 1);
            app.y_damageavg=zeros(size(grouptable, 1), 1);
            app.y_batteryusageavg=zeros(size(grouptable, 1), 1);
            app.y_speedavg=zeros(size(grouptable, 1), 1);
            app.y_traveledavg=zeros(size(grouptable, 1), 1);
            app.generatetree();
            app.Tree_2CheckedNodesChanged();
            app.Batteryusagedata=0;

            app.kmMenu.Checked="off";
            app.mMenu.Checked="on";
            app.multiplier=1000;
        end

        % Callback function: Tree_2
        function Tree_2CheckedNodesChanged(app, event)
            length=size(app.Tree_2.CheckedNodes, 1);
                app.selectedvehicles=[];
            for i=1:length
                app.selectedvehicles=[app.selectedvehicles; app.Tree_2.CheckedNodes(i).NodeData];
            end
            app.VisiblevehiclesPanel.Title=num2str(size(app.selectedvehicles));
        end

        % Menu selected function: kmMenu
        function kmMenuSelected(app, event)
            app.mMenu.Checked="off";
            app.multiplier=1;
            app.kmMenu.Checked="on";
            app.UIAxesTraveled.Title.String="Traveled km";
        end

        % Menu selected function: mMenu
        function mMenuSelected(app, event)
            app.kmMenu.Checked="off";
            app.multiplier=1000;
            app.mMenu.Checked="on";
            app.UIAxesTraveled.Title.String="Traveled m";
        end

        % Menu selected function: ShowvehiclepanelMenu
        function ShowvehiclepanelMenuSelected(app, event)
            if app.ShowvehiclepanelMenu.Checked=="on"
                app.ShowvehiclepanelMenu.Checked="off";
                app.VisiblevehiclesPanel.Visible="off";
                app.GridLayout.ColumnWidth={'0x', '3.9x', '3.9x'};

            else
                app.ShowvehiclepanelMenu.Checked="on";
                app.VisiblevehiclesPanel.Visible="on";
                app.GridLayout.ColumnWidth={'1x', '3.9x', '3.9x'};
            end
        end

        % Menu selected function: ExportdataMenu
        function ExportdataMenuSelected(app, event)
            [file, path]=uiputfile('*.xlsx');
            [nametable, avgtable]=generatenamestab(app);
            for i=1:4
                writematrix(nametable, [path, file], "Sheet", i, "Range", "A2");
                writematrix(app.x_axis, [path, file], "Sheet", i, "Range", "B1");
            end
            
            writematrix(app.y_batteryusage,[path, file], 'Sheet', 1, "Range", "B2");
            writematrix('robots/step[battery usage]',[path, file], 'Sheet', 1, "Range", "A1")
            writematrix(app.y_traveled,[path, file], 'Sheet', 2, "Range", "B2");
            writematrix('robots/step[Traveled distance]',[path, file], 'Sheet', 2, "Range", "A1")
            writematrix(app.y_damage,[path, file], 'Sheet', 3, "Range", "B2");
            writematrix('robots/step[Damage]',[path, file], 'Sheet', 3, "Range", "A1")
            writematrix(app.y_speed,[path, file], 'Sheet', 4, "Range", "B2");
            writematrix('robots/step[Speed]',[path, file],  'Sheet', 4, "Range", "A1")

            avgindex=strcat('A', num2str(1+1+sum(app.grouptable)));

            for i=1:4
            writematrix(avgtable, [path, file], 'Sheet', i, "Range", avgindex);
            end

            avgindex=strcat('B', num2str(1+1+sum(app.grouptable)));

            writematrix(app.y_batteryusageavg, [path, file], 'Sheet', 1, "Range", avgindex);
            writematrix(app.y_traveledavg, [path, file], 'Sheet', 2, "Range", avgindex);
            writematrix(app.y_damageavg, [path, file], 'Sheet', 3, "Range", avgindex);
            writematrix(app.y_speedavg, [path, file], 'Sheet', 4, "Range", avgindex);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataPlotsUIFigure = uifigure('Visible', 'off');
            app.DataPlotsUIFigure.Name = 'Data Plots';
            app.DisplayoptionsMenu = uimenu(app.DataPlotsUIFigure);
            app.DisplayoptionsMenu.Text = 'Display options';
            app.ShowvehiclepanelMenu = uimenu(app.DisplayoptionsMenu);
            app.ShowvehiclepanelMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowvehiclepanelMenuSelected, true);
            app.ShowvehiclepanelMenu.Checked = 'on';
            app.ShowvehiclepanelMenu.Text = 'Show vehicle panel';
            app.TraveleddistanceunitsMenu = uimenu(app.DisplayoptionsMenu);
            app.TraveleddistanceunitsMenu.Text = 'Traveled distance units';
            app.kmMenu = uimenu(app.TraveleddistanceunitsMenu);
            app.kmMenu.MenuSelectedFcn = createCallbackFcn(app, @kmMenuSelected, true);
            app.kmMenu.Checked = 'on';
            app.kmMenu.Text = 'km';
            app.mMenu = uimenu(app.TraveleddistanceunitsMenu);
            app.mMenu.MenuSelectedFcn = createCallbackFcn(app, @mMenuSelected, true);
            app.mMenu.Checked = 'on';
            app.mMenu.Text = 'm';
            app.ImportmodeMenu = uimenu(app.DataPlotsUIFigure);
            app.ImportmodeMenu.Text = 'Import mode';
            app.ExportdataMenu = uimenu(app.DataPlotsUIFigure);
            app.ExportdataMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportdataMenuSelected, true);
            app.ExportdataMenu.Text = 'Export data';
            app.GridLayout = uigridlayout(app.DataPlotsUIFigure);
            app.UIAxesTraveled = uiaxes(app.GridLayout);
            title(app.UIAxesTraveled, 'Traveled m')
            zlabel(app.UIAxesTraveled, 'Z')
            app.UIAxesSpeed = uiaxes(app.GridLayout);
            title(app.UIAxesSpeed, 'Speed')
            zlabel(app.UIAxesSpeed, 'Z')
            app.UIAxesDamage = uiaxes(app.GridLayout);
            title(app.UIAxesDamage, 'Picked Strawberries amount')
            zlabel(app.UIAxesDamage, 'Z')
            app.UIAxesBatteryUsage = uiaxes(app.GridLayout);
            title(app.UIAxesBatteryUsage, 'Battery usage')
            zlabel(app.UIAxesBatteryUsage, 'Z')
            app.VisiblevehiclesPanel = uipanel(app.GridLayout);
            app.VisiblevehiclesPanel.Title = 'Visible vehicles';
            app.Tree_2 = uitree(app.VisiblevehiclesPanel, 'checkbox');
            % Assign Checked Nodes
            app.Tree_2.CheckedNodesChangedFcn = createCallbackFcn(app, @Tree_2CheckedNodesChanged, true);
            % Show the figure after all components are created
            app.DataPlotsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dataplots(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataPlotsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataPlotsUIFigure)
        end
    end
end