classdef greenhouse_editor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        SaveasMenu                     matlab.ui.container.Menu
        LoadMenu                       matlab.ui.container.Menu
        DiscardchangesandexitMenu      matlab.ui.container.Menu
        VisibilityPanel                matlab.ui.container.Panel
        Tree                           matlab.ui.container.CheckBoxTree
        AllNode                        matlab.ui.container.TreeNode
        BoundariesNode_2               matlab.ui.container.TreeNode
        RidgesNode                     matlab.ui.container.TreeNode
        FruitDistributionNode          matlab.ui.container.TreeNode
        VehiclesNode                   matlab.ui.container.TreeNode
        ObstaclesNode                  matlab.ui.container.TreeNode
        OthersNode                     matlab.ui.container.TreeNode
        LoadStationsNode               matlab.ui.container.TreeNode
        TabGroup                       matlab.ui.container.TabGroup
        GeneralTab                     matlab.ui.container.Tab
        HumidityEditField              matlab.ui.control.NumericEditField
        HumidityEditFieldLabel         matlab.ui.control.Label
        InsolationEditField            matlab.ui.control.NumericEditField
        InsolationEditFieldLabel       matlab.ui.control.Label
        TotalgreenhousewidthxdmEditField  matlab.ui.control.NumericEditField
        TotalgreenhousewidthxdmEditFieldLabel  matlab.ui.control.Label
        TotalgreenhouselengthxdmEditField  matlab.ui.control.NumericEditField
        TotalgreenhouselengthxdmEditFieldLabel  matlab.ui.control.Label
        RidgesTab                      matlab.ui.container.Tab
        CurrentchuteparametersPanel    matlab.ui.container.Panel
        RandomseedButton               matlab.ui.control.Button
        NumofcurrentSpinner            matlab.ui.control.Spinner
        NumofcurrentSpinnerLabel       matlab.ui.control.Label
        OpendetailedchuteeditorButton  matlab.ui.control.Button
        FruiteditorButton              matlab.ui.control.Button
        TotalweightoffruitsEditField   matlab.ui.control.NumericEditField
        TotalweightoffruitsEditFieldLabel  matlab.ui.control.Label
        TotalamountoffruitsEditField   matlab.ui.control.NumericEditField
        TotalamountoffruitsEditFieldLabel  matlab.ui.control.Label
        RemoveselectedButton           matlab.ui.control.Button
        WidthEditField                 matlab.ui.control.NumericEditField
        WidthEditFieldLabel            matlab.ui.control.Label
        EndpointYEditField             matlab.ui.control.NumericEditField
        EndpointYEditFieldLabel        matlab.ui.control.Label
        EndpointXEditField             matlab.ui.control.NumericEditField
        EndpointXEditFieldLabel        matlab.ui.control.Label
        StartpointYEditField           matlab.ui.control.NumericEditField
        StartpointYEditFieldLabel      matlab.ui.control.Label
        StartpointXEditField           matlab.ui.control.NumericEditField
        StartpointXEditFieldLabel      matlab.ui.control.Label
        NewchuteparametersPanel        matlab.ui.container.Panel
        AddnewButton                   matlab.ui.control.Button
        WidthEditField_New             matlab.ui.control.NumericEditField
        WidthEditField_2Label          matlab.ui.control.Label
        EndpointYEditField_New         matlab.ui.control.NumericEditField
        EndpointYEditField_2Label      matlab.ui.control.Label
        EndpointXEditField_New         matlab.ui.control.NumericEditField
        EndpointXEditField_2Label      matlab.ui.control.Label
        StartpointYEditField_New       matlab.ui.control.NumericEditField
        StartpointYEditField_2Label    matlab.ui.control.Label
        StartpointXEditField_New       matlab.ui.control.NumericEditField
        StartpointXEditField_2Label    matlab.ui.control.Label
        FruitDistributionTab           matlab.ui.container.Tab
        NextdayButton                  matlab.ui.control.Button
        CurrentdaySpinner              matlab.ui.control.Spinner
        CurrentdaySpinnerLabel         matlab.ui.control.Label
        TotalamountLabel               matlab.ui.control.Label
        RottenLabel                    matlab.ui.control.Label
        UnmatureLabel                  matlab.ui.control.Label
        MatureLabel                    matlab.ui.control.Label
        VehiclesTab                    matlab.ui.container.Tab
        ObstaclesTab                   matlab.ui.container.Tab
        OthersTab                      matlab.ui.container.Tab
        SimulationTab                  matlab.ui.container.Tab
        AnalysisTab                    matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        polygondata
        polygonhandlearray
        vehicles_polygons;
        vehicles_coords;
        fruitsarray;
        days;
    end


    methods (Access = private)
        
        function dist = distance(~, xa, xb, ya, yb)
            dist = sqrt((xa-xb)^2+(ya-yb)^2);
        end
   
        function color = generatecolor(app, i, j)
            color = [1 1 1];

            t = states(app, i, j);

            if(t(4)>0)
                r = t(2)/t(4);
                g = t(1)/t(4);
                b = t(3)/t(4);

            color = [r g b];
            end


        end

        function t = vehiclecoordstopolyparams(app, vehx, vehy, width, length)
            X = [vehx-width/2, vehx, vehx+width/2, vehx];
            Y = [vehy, vehy+length/2, vehy, vehy-length/2];
            t=[X;Y];
        end    

        function loadgraphics(app)
            for i=1:size(app.vehicles_coords, 2)
                x=app.vehicles_coords(i,1);
                y=app.vehicles_coords(i,2);
                t = app.vehiclecoordstopolyparams(x, y, 7, 2);
                app.vehicles_polygons=[app.vehicles_polygons fill(app.UIAxes, t(1,:), t(2, :), 'r')];

            end

        end
        function movevehicle(app, numofvehicle, deltax, deltay)
            app.vehicles_polygons(numofvehicle).Vertices(:, 1)=app.vehicles_polygons(numofvehicle).Vertices(:,1)+deltax;
            app.vehicles_polygons(numofvehicle).Vertices(:, 2)=app.vehicles_polygons(numofvehicle).Vertices(:,2)+deltay;

        end

        function printgraphics(app)
            app.vehicles_polygons(1).Vertices(:, 1)=app.vehicles_polygons(1).Vertices(:,1)+100;


        end
    
        function updatelabels(app, unmature, mature, rotten, total)
            text1=strcat("Unmature %: ", num2str(unmature*100/total, 4), "[", num2str(unmature), "]");
            text2=strcat("Mature %: ", num2str(mature*100/total, 4), "[", num2str(mature), "]");
            text3=strcat("Rotten %: ", num2str(rotten*100/total, 4), "[", num2str(rotten), "]");
            text4=strcat("Total: ", num2str(total));
            app.UnmatureLabel.Text=text1;
            app.MatureLabel.Text=text2;
            app.RottenLabel.Text=text3;
            app.TotalamountLabel.Text=text4;

        end

        function generateridge(app, xa, xb, ya, yb, width, scale)
                l=distance(app,xa,xb,ya,yb);
                R=(scale/l)*[xb-xa;yb-ya];
                r1vector=(width/(2*l))*[yb-ya;xa-xb];
                r2vector=(width/(2*l))*[ya-yb;xb-xa];
                n1=[xa;ya]+r1vector;
                m1=[xa;ya];
                m2=n1;
                m3=m2+R;
                m4=m1+R;
                mpolygonparams=[m1 m2 m3 m4];

                app.polygondata = [app.polygondata; xa, xb, ya, yb, width, scale];

                newpolygonhandleline = zeros(1, 89);
             for i=1:(l/scale)
                 temppolygon=mpolygonparams + R*(i-1);
                 newpolygonhandleline(i) = fill(app.UIAxes, temppolygon(1, :), temppolygon(2, :), 'r', "LineWidth",0.05);
                 hold(app.UIAxes, "on");
                
             end
                app.polygonhandlearray=[app.polygonhandlearray; newpolygonhandleline];
             mpolygonparams=mpolygonparams+r2vector;

                newpolygonhandleline = zeros(1, 89);
             for i=1:(l/scale)
                 temppolygon=mpolygonparams + R*(i-1);
                 newpolygonhandleline(i) = fill(app.UIAxes, temppolygon(1, :), temppolygon(2, :), 'r', "LineWidth",0.05);
                 hold(app.UIAxes, "on");
                
             end
                app.polygonhandlearray=[app.polygonhandlearray; newpolygonhandleline];

        end   

        function t = states(app, i, j)
            temp = app.fruitsarray{i, j};
            total=size(temp, 2);
            unmature = 0;
            mature = 0;
            rotten = 0;
        
            if(total>0)
                for k=1:total
                    if(app.fruitsarray{i,j}(k).State==0)
                        unmature=unmature+1;
                    elseif(app.fruitsarray{i,j}(k).State==1)
                        mature=mature+1;
                    elseif(app.fruitsarray{i,j}(k).State==-1)
                        rotten=rotten+1;
                    end
            
                end
            end

            t = [unmature mature rotten total];
    end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.XTick=0:25:900;
            app.UIAxes.YTick=0:5:96;

            rectangle(app.UIAxes, "Position", [0 0 900 96], "FaceColor", [0.5137 0.98 0.86], "EdgeColor", [0 0 0], "LineWidth", 3);
            hold(app.UIAxes, "on");
            app.vehicles_coords=[1,1; 10,1];
            app.loadgraphics;
            for i=1:1000
                angle = deg2rad(i);
                app.movevehicle(1, 0.5*sin(angle), 0.5*cos(angle));
                
                pause(1/60);

            end
        end

        % Button pushed function: AddnewButton
        function AddnewButtonPushed(app, event)
            xa=app.StartpointXEditField_New.Value;
            xb=app.EndpointXEditField_New.Value;
            ya=app.StartpointYEditField_New.Value;
            yb=app.EndpointYEditField_New.Value;
            width=app.WidthEditField_New.Value;
            scale = 16;


            app.generateridge(xa, xb, ya, yb, width, scale);
            hold(app.UIAxes, "on");

            if(length(app.polygonhandlearray)>1)
                app.NumofcurrentSpinner.Enable=1;
                app.NumofcurrentSpinner.Limits=[1, length(app.polygonhandlearray)];
                app.polygonhandlearray(end).EdgeColor="green";
                app.NumofcurrentSpinner.Value=length(app.polygonhandlearray);

            else 
                app.polygonhandlearray(1).EdgeColor="green";
                app.NumofcurrentSpinner.Value=1;
                app.NumofcurrentSpinner.Enable=0;
            end
        end

        % Value changed function: NumofcurrentSpinner
        function NumofcurrentSpinnerValueChanged(app, event)
            value = app.NumofcurrentSpinner.Value;
            app.polygonhandlearray(value).EdgeColor="green";
        end

        % Button pushed function: RemoveselectedButton
        function RemoveselectedButtonPushed(app, event)
            if(~isempty(app.polygonhandlearray))
                value = app.NumofcurrentSpinner.Value;
                if(and(value==size(app.polygonhandlearray),value>1))
                     app.NumofcurrentSpinner.Value=app.NumofcurrentSpinner.Value-1;
                end
                delete(app.polygonhandlearray(value));
                app.polygonhandlearray(value)=[];
    
    
                if (value>2)
                    app.NumofcurrentSpinner.Limits=[1, app.NumofcurrentSpinner.Limits(2)-1];
                else
                    app.NumofcurrentSpinner.Enable=0;
                end
            end
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            [file,path,~] = uiputfile({'*.csv'});
        
            %{
            for i=1:size(app.polygonhandlearray, 2)
                temp = app.polygonhandlearray(i).Vertices';
                app.polygondata = [app.polygondata; temp];
            end
            %}

            writematrix(app.polygondata, strcat(path, file),'Delimiter', 'tab');


        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            delete(app.polygonhandlearray);
            [file,path,~] = uigetfile({'*.csv'});
            app.polygondata=readmatrix(strcat(path,file));
            
            hold(app.UIAxes, "on");
            %{
            for i=1:2:size(app.polygondata, 1)
                t = fill(app.UIAxes, app.polygondata(i, :), app.polygondata(i+1, :), 'r');
                app.polygonhandlearray=[app.polygonhandlearray t];

            end
            %}
            for i=1:size(app.polygondata, 1)
                t = app.polygondata(i, :);
                app.generateridge(t(1), t(2), t(3), t(4), t(5), t(6));
            end

        end

        % Button pushed function: RandomseedButton
        function RandomseedButtonPushed(app, event)
            app.fruitsarray = cell(88,16);
            %s = Strawberry(1, 0, 1, 1);
            for i=1:16
                for j=1:88
                    n = randi(21)-1;
                    temp = [];
                    if(n>0)
                        for k=1:n
                            n = Strawberry(1, 0, 1, 1);
                            temp = [temp n];
                        end
                    end
                    app.fruitsarray{i, j}=temp;
                end
            end

       
            for i=1:16
                for j =1:88
                    set(app.polygonhandlearray(i, j), 'FaceColor', app.generatecolor(i, j));
                end

            end

            app.days=app.fruitsarray;
            %set(app.polygonhandlearray(i, j), 'FaceColor', color);
        end

        % Button pushed function: NextdayButton
        function NextdayButtonPushed(app, event)
            totalunmature=0;totalmature=0;totalrotten=0;total=0; 
            t = [totalunmature, totalmature, totalrotten, total];
            for i=1:16
                for j=1:88
                    temp = app.fruitsarray{i, j};
                    n = size(temp, 2);
                    if(n>0)
                        for k=1:n
                            GrowthModel(app.fruitsarray{i,j}(k));
                        end
                    end
                    set(app.polygonhandlearray(i, j), 'FaceColor', app.generatecolor(i, j));
                    t = t + app.states(i,j);
                end
            end
            app.updatelabels(t(1), t(2), t(3), t(4));
            app.CurrentdaySpinner.Value= app.CurrentdaySpinner.Value+1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.DiscardchangesandexitMenu = uimenu(app.UIFigure);
            app.DiscardchangesandexitMenu.Text = 'Discard changes and exit';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Greenhouse view')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 900];
            app.UIAxes.YLim = [0 96];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.TotalgreenhouselengthxdmEditFieldLabel = uilabel(app.GeneralTab);
            app.TotalgreenhouselengthxdmEditFieldLabel.Text = 'Total greenhouse length[x, dm]:';
            app.TotalgreenhouselengthxdmEditField = uieditfield(app.GeneralTab, 'numeric');
            app.TotalgreenhouselengthxdmEditField.Value = 900;
            app.TotalgreenhousewidthxdmEditFieldLabel = uilabel(app.GeneralTab);
            app.TotalgreenhousewidthxdmEditFieldLabel.Text = 'Total greenhouse width[x, dm]:';
            app.TotalgreenhousewidthxdmEditField = uieditfield(app.GeneralTab, 'numeric');
            app.TotalgreenhousewidthxdmEditField.Value = 96;
            app.InsolationEditFieldLabel = uilabel(app.GeneralTab);
            app.InsolationEditFieldLabel.Text = 'Insolation[%]';
            app.InsolationEditField = uieditfield(app.GeneralTab, 'numeric');
            app.HumidityEditFieldLabel = uilabel(app.GeneralTab);
            app.HumidityEditFieldLabel.Text = 'Humidity[%]';
            app.HumidityEditField = uieditfield(app.GeneralTab, 'numeric');
            app.RidgesTab = uitab(app.TabGroup);
            app.RidgesTab.Title = 'Ridges';
            app.NewchuteparametersPanel = uipanel(app.RidgesTab);
            app.NewchuteparametersPanel.Title = 'New chute parameters';
            app.StartpointXEditField_2Label = uilabel(app.NewchuteparametersPanel);
            app.StartpointXEditField_2Label.Text = 'Start point X';
            app.StartpointXEditField_New = uieditfield(app.NewchuteparametersPanel, 'numeric');
            app.StartpointYEditField_2Label = uilabel(app.NewchuteparametersPanel);
            app.StartpointYEditField_2Label.Text = 'Start point Y';
            app.StartpointYEditField_New = uieditfield(app.NewchuteparametersPanel, 'numeric');
            app.EndpointXEditField_2Label = uilabel(app.NewchuteparametersPanel);
            app.EndpointXEditField_2Label.Text = 'End point X';
            app.EndpointXEditField_New = uieditfield(app.NewchuteparametersPanel, 'numeric');
            app.EndpointXEditField_New.Value = 500;
            app.EndpointYEditField_2Label = uilabel(app.NewchuteparametersPanel);
            app.EndpointYEditField_2Label.Text = 'End point Y';
            app.EndpointYEditField_New = uieditfield(app.NewchuteparametersPanel, 'numeric');
            app.EndpointYEditField_New.Value = 25;
            app.WidthEditField_2Label = uilabel(app.NewchuteparametersPanel);
            app.WidthEditField_2Label.Text = 'Width';
            app.WidthEditField_New = uieditfield(app.NewchuteparametersPanel, 'numeric');
            app.WidthEditField_New.Value = 50;
            app.AddnewButton = uibutton(app.NewchuteparametersPanel, 'push');
            app.AddnewButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewButtonPushed, true);
            app.AddnewButton.Text = 'Add new!';
            app.CurrentchuteparametersPanel = uipanel(app.RidgesTab);
            app.CurrentchuteparametersPanel.Title = 'Current chute parameters';
            app.StartpointXEditFieldLabel = uilabel(app.CurrentchuteparametersPanel);
            app.StartpointXEditFieldLabel.Text = 'Start point X';
            app.StartpointXEditField = uieditfield(app.CurrentchuteparametersPanel, 'numeric');
            app.StartpointYEditFieldLabel = uilabel(app.CurrentchuteparametersPanel);
            app.StartpointYEditFieldLabel.Text = 'Start point Y';
            app.StartpointYEditField = uieditfield(app.CurrentchuteparametersPanel, 'numeric');
            app.EndpointXEditFieldLabel = uilabel(app.CurrentchuteparametersPanel);
            app.EndpointXEditFieldLabel.Text = 'End point X';
            app.EndpointXEditField = uieditfield(app.CurrentchuteparametersPanel, 'numeric');
            app.EndpointYEditFieldLabel = uilabel(app.CurrentchuteparametersPanel);
            app.EndpointYEditFieldLabel.Text = 'End point Y';
            app.EndpointYEditField = uieditfield(app.CurrentchuteparametersPanel, 'numeric');
            app.WidthEditFieldLabel = uilabel(app.CurrentchuteparametersPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.CurrentchuteparametersPanel, 'numeric');
            app.RemoveselectedButton = uibutton(app.CurrentchuteparametersPanel, 'push');
            app.RemoveselectedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveselectedButtonPushed, true);
            app.RemoveselectedButton.Text = 'Remove selected';
            app.TotalamountoffruitsEditFieldLabel = uilabel(app.CurrentchuteparametersPanel);
            app.TotalamountoffruitsEditFieldLabel.Text = 'Total amount of fruits';
            app.TotalamountoffruitsEditField = uieditfield(app.CurrentchuteparametersPanel, 'numeric');
            app.TotalweightoffruitsEditFieldLabel = uilabel(app.CurrentchuteparametersPanel);
            app.TotalweightoffruitsEditFieldLabel.Text = 'Total weight of fruits';
            app.TotalweightoffruitsEditField = uieditfield(app.CurrentchuteparametersPanel, 'numeric');
            app.FruiteditorButton = uibutton(app.CurrentchuteparametersPanel, 'push');
            app.FruiteditorButton.Text = 'Fruit editor';
            app.OpendetailedchuteeditorButton = uibutton(app.CurrentchuteparametersPanel, 'push');
            app.OpendetailedchuteeditorButton.Text = 'Open detailed chute editor';
            app.NumofcurrentSpinnerLabel = uilabel(app.CurrentchuteparametersPanel);
            app.NumofcurrentSpinnerLabel.Enable = 'off';
            app.NumofcurrentSpinnerLabel.Text = 'Num. of current';
            app.NumofcurrentSpinner = uispinner(app.CurrentchuteparametersPanel);
            app.NumofcurrentSpinner.Limits = [1 2];
            app.NumofcurrentSpinner.ValueChangedFcn = createCallbackFcn(app, @NumofcurrentSpinnerValueChanged, true);
            app.NumofcurrentSpinner.Editable = 'off';
            app.NumofcurrentSpinner.Enable = 'off';
            app.NumofcurrentSpinner.Value = 1;
            app.RandomseedButton = uibutton(app.CurrentchuteparametersPanel, 'push');
            app.RandomseedButton.ButtonPushedFcn = createCallbackFcn(app, @RandomseedButtonPushed, true);
            app.RandomseedButton.Text = 'Random seed';
            app.FruitDistributionTab = uitab(app.TabGroup);
            app.FruitDistributionTab.Title = 'Fruit Distribution';
            app.MatureLabel = uilabel(app.FruitDistributionTab);
            app.MatureLabel.Text = 'Mature %:';
            app.UnmatureLabel = uilabel(app.FruitDistributionTab);
            app.UnmatureLabel.Text = 'Unmature %:';
            app.RottenLabel = uilabel(app.FruitDistributionTab);
            app.RottenLabel.Text = 'Rotten %:';
            app.TotalamountLabel = uilabel(app.FruitDistributionTab);
            app.TotalamountLabel.Text = 'Total amount:';
            app.CurrentdaySpinnerLabel = uilabel(app.FruitDistributionTab);
            app.CurrentdaySpinnerLabel.Text = {'Current'; 'day'};
            app.CurrentdaySpinner = uispinner(app.FruitDistributionTab);
            app.CurrentdaySpinner.Editable = 'off';
            app.NextdayButton = uibutton(app.FruitDistributionTab, 'push');
            app.NextdayButton.ButtonPushedFcn = createCallbackFcn(app, @NextdayButtonPushed, true);
            app.NextdayButton.Text = 'Next day';
            app.VehiclesTab = uitab(app.TabGroup);
            app.VehiclesTab.Title = 'Vehicles';
            app.ObstaclesTab = uitab(app.TabGroup);
            app.ObstaclesTab.Title = 'Obstacles';
            app.OthersTab = uitab(app.TabGroup);
            app.OthersTab.Title = 'Others';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.VisibilityPanel = uipanel(app.UIFigure);
            app.VisibilityPanel.Title = 'Visibility';
            app.Tree = uitree(app.VisibilityPanel, 'checkbox');
            app.AllNode = uitreenode(app.Tree);
            app.AllNode.Text = 'All';
            app.BoundariesNode_2 = uitreenode(app.AllNode);
            app.BoundariesNode_2.Text = 'Boundaries';
            app.RidgesNode = uitreenode(app.AllNode);
            app.RidgesNode.Text = 'Ridges';
            app.FruitDistributionNode = uitreenode(app.AllNode);
            app.FruitDistributionNode.Text = 'Fruit Distribution';
            app.VehiclesNode = uitreenode(app.AllNode);
            app.VehiclesNode.Text = 'Vehicles';
            app.ObstaclesNode = uitreenode(app.AllNode);
            app.ObstaclesNode.Text = 'Obstacles';
            app.OthersNode = uitreenode(app.AllNode);
            app.OthersNode.Text = 'Others';
            app.LoadStationsNode = uitreenode(app.OthersNode);
            app.LoadStationsNode.Text = 'Load Stations';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = greenhouse_editor

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end