classdef vehicles_editor_strawberries < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MachineEditorUIFigure           matlab.ui.Figure
        ArmparametersPanel              matlab.ui.container.Panel
        lengthmEditField                matlab.ui.control.NumericEditField
        lengthmEditFieldLabel           matlab.ui.control.Label
        LiftingcapacitykgEditField      matlab.ui.control.NumericEditField
        LiftingcapacitykgEditFieldLabel  matlab.ui.control.Label
        degreesoffreedomEditField       matlab.ui.control.NumericEditField
        degreesoffreedomEditFieldLabel  matlab.ui.control.Label
        weightmEditField                matlab.ui.control.NumericEditField
        weightmEditFieldLabel           matlab.ui.control.Label
        heightmEditField_2              matlab.ui.control.NumericEditField
        heightmEditField_2Label         matlab.ui.control.Label
        ArmImage                        matlab.ui.control.Image
        ArmtypeDropDown                 matlab.ui.control.DropDown
        ArmtypeDropDownLabel            matlab.ui.control.Label
        VehicleparametersPanel          matlab.ui.container.Panel
        CapacitykgEditField             matlab.ui.control.NumericEditField
        CapacitykgEditFieldLabel        matlab.ui.control.Label
        SaveConfigButton                matlab.ui.control.Button
        MinspeedmsnegativevaluesifcanridereverseEditField  matlab.ui.control.NumericEditField
        MinspeedmsnegativevaluesifcanridereverseLabel  matlab.ui.control.Label
        MaxspeedmsEditField             matlab.ui.control.NumericEditField
        MaxspeedmsEditFieldLabel        matlab.ui.control.Label
        WeightkgEditField_2             matlab.ui.control.NumericEditField
        WeightkgEditField_2Label        matlab.ui.control.Label
        widthmEditField                 matlab.ui.control.NumericEditField
        widthmEditFieldLabel            matlab.ui.control.Label
        heightmEditField                matlab.ui.control.NumericEditField
        heightmEditFieldLabel           matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        ListBox                         matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        callingapp % Description
        vehicle_template_array
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.callingapp=mainapp;
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            num = app.ListBox.Value;
            app.VehicleparametersPanel.Title=num2str(num);
            app.SteeringradiusmEditField.Value=app.vehicle_template_array(num).radius;
            app.NameEditField.Value=app.vehicle_template_array(num).Name;
            app.WeightkgEditField_2.Value=app.vehicle_template_array(num).weight;
            app.lengthmEditField.Value=app.vehicle_template_array(num).length;
            app.widthmEditField.Value=app.vehicle_template_array(num).width;
            app.heightmEditField.Value=app.vehicle_template_array(num).height;
            app.NumberofaxesEditField.Value=app.vehicle_template_array(num).axes;
            app.MaxspeedmsEditField.Value=app.vehicle_template_array(num).max_speed;
            app.MinspeedmsnegativevaluesifcanridereverseEditField.Value=app.vehicle_template_array(num).min_speed;

        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
            num = app.ListBox.Value;
            app.VehicleparametersPanel.Title=app.NameEditField.Value;
            app.vehicle_template_array(num).radius=app.SteeringradiusmEditField.Value;
            app.vehicle_template_array(num).Name=app.NameEditField.Value;
            app.vehicle_template_array(num).weight=app.WeightkgEditField_2.Value;
            app.vehicle_template_array(num).length=app.lengthmEditField.Value;
            app.vehicle_template_array(num).width=app.widthmEditField.Value;
            app.vehicle_template_array(num).height=app.heightmEditField.Value;
            app.vehicle_template_array(num).axes=app.NumberofaxesEditField.Value;
            app.vehicle_template_array(num).max_speed=app.MaxspeedmsEditField.Value;
            app.vehicle_template_array(num).min_speed=app.MinspeedmsnegativevaluesifcanridereverseEditField.Value;
            app.VehicleparametersPanel.Title=app.vehicle_template_array(num).Name;
            app.ListBox.Items=app.vehicle_template_array(num).Name;
            app.ListBox.Items=[app.ListBox.Items 'test'];
        end

        % Callback function
        function SaveMenuSelected(app, event)
            import_vehicle_template(app, app.vehicle_template_array);
        end

        % Value changed function: ArmtypeDropDown
        function ArmtypeDropDownValueChanged(app, event)
            selected_arm = app.ArmtypeDropDown.Value;
        switch selected_arm
            case 'Arm 1'
                app.ArmImage.ImageSource='arms/arm1.png';
            case 'Arm 2'
                app.ArmImage.ImageSource='arms/arm2.jpg';
            case 'Arm 3'
                app.ArmImage.ImageSource='arms/arm3.jpg';

        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MachineEditorUIFigure = uifigure('Visible', 'off');
            app.MachineEditorUIFigure.Name = 'Machine Editor';
            app.ListBox = uilistbox(app.MachineEditorUIFigure);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.TextArea = uitextarea(app.MachineEditorUIFigure);
            app.VehicleparametersPanel = uipanel(app.MachineEditorUIFigure);
            app.VehicleparametersPanel.Title = 'Vehicle parameters';
            app.heightmEditFieldLabel = uilabel(app.VehicleparametersPanel);
            app.heightmEditFieldLabel.Text = 'height[m]';
            app.heightmEditField = uieditfield(app.VehicleparametersPanel, 'numeric');
            app.widthmEditFieldLabel = uilabel(app.VehicleparametersPanel);
            app.widthmEditFieldLabel.Text = 'width[m]';
            app.widthmEditField = uieditfield(app.VehicleparametersPanel, 'numeric');
            app.WeightkgEditField_2Label = uilabel(app.VehicleparametersPanel);
            app.WeightkgEditField_2Label.Text = 'Weight[kg]';
            app.WeightkgEditField_2 = uieditfield(app.VehicleparametersPanel, 'numeric');
            app.MaxspeedmsEditFieldLabel = uilabel(app.VehicleparametersPanel);
            app.MaxspeedmsEditFieldLabel.Text = 'Max. speed[m/s]';
            app.MaxspeedmsEditField = uieditfield(app.VehicleparametersPanel, 'numeric');
            app.MinspeedmsnegativevaluesifcanridereverseLabel = uilabel(app.VehicleparametersPanel);
            app.MinspeedmsnegativevaluesifcanridereverseLabel.Text = {'Min. speed[m/s]'; '(negative values if '; 'can ride reverse)'};
            app.MinspeedmsnegativevaluesifcanridereverseEditField = uieditfield(app.VehicleparametersPanel, 'numeric');
            app.SaveConfigButton = uibutton(app.VehicleparametersPanel, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Text = 'Save Config';
            app.CapacitykgEditFieldLabel = uilabel(app.VehicleparametersPanel);
            app.CapacitykgEditFieldLabel.Text = 'Capacity[kg]';
            app.CapacitykgEditField = uieditfield(app.VehicleparametersPanel, 'numeric');
            app.ArmtypeDropDownLabel = uilabel(app.MachineEditorUIFigure);
            app.ArmtypeDropDownLabel.Enable = 'off';
            app.ArmtypeDropDownLabel.Text = 'Arm type:';
            app.ArmtypeDropDown = uidropdown(app.MachineEditorUIFigure);
            app.ArmtypeDropDown.Items = {'Arm 1', 'Arm 2', 'Arm 3'};
            app.ArmtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ArmtypeDropDownValueChanged, true);
            app.ArmtypeDropDown.Enable = 'off';
            app.ArmtypeDropDown.Value = 'Arm 1';
            app.ArmImage = uiimage(app.MachineEditorUIFigure);
            app.ArmImage.ImageSource = fullfile(pathToMLAPP, 'arms', 'arm1.png');
            app.ArmparametersPanel = uipanel(app.MachineEditorUIFigure);
            app.ArmparametersPanel.Title = 'Arm parameters';
            app.heightmEditField_2Label = uilabel(app.ArmparametersPanel);
            app.heightmEditField_2Label.Text = 'height[m]';
            app.heightmEditField_2 = uieditfield(app.ArmparametersPanel, 'numeric');
            app.weightmEditFieldLabel = uilabel(app.ArmparametersPanel);
            app.weightmEditFieldLabel.Text = 'weight[m]';
            app.weightmEditField = uieditfield(app.ArmparametersPanel, 'numeric');
            app.degreesoffreedomEditFieldLabel = uilabel(app.ArmparametersPanel);
            app.degreesoffreedomEditFieldLabel.Text = 'degrees of freedom';
            app.degreesoffreedomEditField = uieditfield(app.ArmparametersPanel, 'numeric');
            app.LiftingcapacitykgEditFieldLabel = uilabel(app.ArmparametersPanel);
            app.LiftingcapacitykgEditFieldLabel.Text = 'Lifting capacity[kg]';
            app.LiftingcapacitykgEditField = uieditfield(app.ArmparametersPanel, 'numeric');
            app.lengthmEditFieldLabel = uilabel(app.ArmparametersPanel);
            app.lengthmEditFieldLabel.Text = 'length[m]';
            app.lengthmEditField = uieditfield(app.ArmparametersPanel, 'numeric');
            % Show the figure after all components are created
            app.MachineEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vehicles_editor_strawberries(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MachineEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MachineEditorUIFigure)
        end
    end
end